classdef pTAM_Fitting_App_v2_ClinEPR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FitSpectrumButton            matlab.ui.control.Button
        FieldSwitch                  matlab.ui.control.Switch
        CalibrationsandResultsPanel  matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        PhosphateResult              matlab.ui.control.NumericEditField
        pHResult                     matlab.ui.control.NumericEditField
        OxygenResult                 matlab.ui.control.NumericEditField
        PhosphateSlope               matlab.ui.control.NumericEditField
        pKa                          matlab.ui.control.NumericEditField
        OxygenSlope                  matlab.ui.control.NumericEditField
        s1mMLabel                    matlab.ui.control.Label
        mGmmHgLabel                  matlab.ui.control.Label
        CorrelationLabel             matlab.ui.control.Label
        pKaLabel                     matlab.ui.control.Label
        kfslopeLabel                 matlab.ui.control.Label
        mMLabel                      matlab.ui.control.Label
        mmHgLabel                    matlab.ui.control.Label
        ResultLabel                  matlab.ui.control.Label
        SlopeLabel                   matlab.ui.control.Label
        PhosphatePiLabel             matlab.ui.control.Label
        pHLabel                      matlab.ui.control.Label
        OxygenpO2Label               matlab.ui.control.Label
        FittingParametersPanel       matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        PhaseResult                  matlab.ui.control.NumericEditField
        CenterResult                 matlab.ui.control.NumericEditField
        PiTermResult                 matlab.ui.control.NumericEditField
        kExchangeResult              matlab.ui.control.NumericEditField
        PaResult                     matlab.ui.control.NumericEditField
        OxygenLWResult               matlab.ui.control.NumericEditField
        ABDistanceResult             matlab.ui.control.NumericEditField
        LWLbResult                   matlab.ui.control.NumericEditField
        LWLaResult                   matlab.ui.control.NumericEditField
        LWGResult                    matlab.ui.control.NumericEditField
        kExchangeRange               matlab.ui.control.NumericEditField
        PaRange                      matlab.ui.control.NumericEditField
        OxygenLWRange                matlab.ui.control.NumericEditField
        ABDistanceRange              matlab.ui.control.NumericEditField
        LWLbRange                    matlab.ui.control.NumericEditField
        LWLaRange                    matlab.ui.control.NumericEditField
        LWGRange                     matlab.ui.control.NumericEditField
        kExchangeSwitch              matlab.ui.control.Switch
        PaSwitch                     matlab.ui.control.Switch
        OxygenLWSwitch               matlab.ui.control.Switch
        ABDistanceSwitch             matlab.ui.control.Switch
        LWLbSwitch                   matlab.ui.control.Switch
        LWLaSwitch                   matlab.ui.control.Switch
        LWGSwitch                    matlab.ui.control.Switch
        PhaseCorr                    matlab.ui.control.NumericEditField
        PhaseCorrectionLabel         matlab.ui.control.Label
        XpositionStart               matlab.ui.control.NumericEditField
        CenteroffsetptsLabel         matlab.ui.control.Label
        kExchangeStart               matlab.ui.control.NumericEditField
        HExchangeHzLabel             matlab.ui.control.Label
        PaStartValue                 matlab.ui.control.NumericEditField
        AcidicFractionLabel          matlab.ui.control.Label
        OxygenLWStart                matlab.ui.control.NumericEditField
        OxygenLWmGLabel              matlab.ui.control.Label
        ABDistanceStart              matlab.ui.control.NumericEditField
        ABDistancemGLabel            matlab.ui.control.Label
        LWLbStart                    matlab.ui.control.NumericEditField
        LorentzianBLWmGLabel         matlab.ui.control.Label
        LWLaStart                    matlab.ui.control.NumericEditField
        LorentzianALWmGLabel         matlab.ui.control.Label
        LWGStart                     matlab.ui.control.NumericEditField
        GaussianLWmGLabel            matlab.ui.control.Label
        PiTerm_Label                 matlab.ui.control.Label
        RangeNote_Label              matlab.ui.control.Label
        BestFitLabel                 matlab.ui.control.Label
        RangeLabel                   matlab.ui.control.Label
        StartValueLabel              matlab.ui.control.Label
        ParameterLabel               matlab.ui.control.Label
        QuickCalButtonsPanel         matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        LineFitting                  matlab.ui.control.Button
        pKaCalButton                 matlab.ui.control.Button
        PiCalButton                  matlab.ui.control.Button
        pH7O2CalButton               matlab.ui.control.Button
        pH10O2CalButton              matlab.ui.control.Button
        pH5O2CalButton               matlab.ui.control.Button
        PiDistButton                 matlab.ui.control.Button
        pH7LWButton                  matlab.ui.control.Button
        pH10LWButton                 matlab.ui.control.Button
        pH5LWButton                  matlab.ui.control.Button
        DeleteCalButton              matlab.ui.control.Button
        SaveAsButton                 matlab.ui.control.Button
        SaveChangesButton            matlab.ui.control.Button
        EditCalCancelButton          matlab.ui.control.Button
        SaveNewCalButton             matlab.ui.control.Button
        SelectCalibrationDropDown    matlab.ui.control.DropDown
        SelectParametersCalibrationDropDownLabel  matlab.ui.control.Label
        SpectrumDataPanel            matlab.ui.container.Panel
        FilePathLabel                matlab.ui.control.Label
        SelectfileButton             matlab.ui.control.Button
        SpectrumFileName             matlab.ui.control.EditField
        DataFileLabel                matlab.ui.control.Label
        SpectrumFilePath             matlab.ui.control.EditField
        SpectralFittingforMonophosphonatedTritylRadicalsLabel  matlab.ui.control.Label
        spectrum_fitting             matlab.ui.control.UIAxes
        spectrum_simulation          matlab.ui.control.UIAxes
    end

   %Requires Signal Processing Toolbox and Statistics and Machine Learning Toolbox


    properties (Access = public)
        calibrations = string.empty;
        calFile = string.empty; %location and file name of saved calibrations
    end
    
    methods (Access = private)
        
        function [SimulSpec] = pTamSpeAuto(app, Spectrumx, Xposition, oxygenLW, ABDistance, kExchange, Pa, LWaZero, LWbZero, LWG, phase0)
            %% pTAM fitting for two lines, using analytical solution
            % by Stephen DeVience and Andrey Bobko
            
            LinewidthA = (LWaZero*1e-3 + oxygenLW*1e-3)*sqrt(3)/2;      % Parameter Linewidth
            LinewidthB = (LWbZero*1e-3 + oxygenLW*1e-3)*sqrt(3)/2;   
            LinewidthG = LWG*1e-3;
            kExchange=kExchange*1e3*pi*4;
            wH=Spectrumx;
            %CenterSpec = length(Spectrumx)/2 + int16(Xposition);
            %CenterField = wH(CenterSpec);
            CenterField=Xposition;
            DistanceField = ABDistance*1e-3;%*(wH(2)-wH(1)) adapted to mG instead of points                   
            waField = CenterField+DistanceField/2;
            wbField = CenterField-DistanceField/2;
            Y = 1.7608597084e7;
            w = Y.*wH;
            wa = Y.*waField;
            wb = Y.*wbField;
            wc = Y.*CenterField;
            for step = 1:1:1
                Btot = 8e-3*step/8;       % Parameter Buffer Concentration
                Kb = 10^(-6.9);
                H = 10^(-6.9);
                BH = H.*Btot/(H+Kb);
                B = Kb.*Btot/(H+Kb);
                ka = kExchange;
                kb = ka*Pa/(1-Pa);                     % Parameter Rate Constant
            ta = 1./ka./BH*2*pi;
            tb = 1./kb./B*2*pi;
            t = 2./(1./ta +1./tb);
            Pb = 1- Pa;
            LWfreqA = Y.*LinewidthA;
            LWfreqB = Y.*LinewidthB;
            LWGfreq = Y.*LinewidthG;
            Ta = 1./LWfreqA;
            Tb = 1./LWfreqB;
            Tg = 1./LWGfreq;
            P = Pa./Ta + Pb./Tb + t./2.*(1./Ta./Tb -(w - (wa+wb)./2).^2 +1/4.*(wa-wb).^2);
            R = (w - (wa+wb)./2).*(1 + t./2.*(1./Ta+1./Tb))+t./4.*(wa-wb).*(1./Ta-1./Tb) - 1/2.*(Pa-Pb).*(wa-wb);
            Q = t./2.*((w - (wa+wb)./2) + 1/2.*(wa-wb).*(Pa-Pb));
            Vr = ((1+t./2.*(Pa./Tb+Pb./Ta)).*P + Q.*R)./(P.^2 +R.^2);
            V=hilbert(Vr);
            s = 1/Tg;
            gsder = -exp(-2.*(w-wc).^2/s^2)/s*sqrt(2)/sqrt(pi).*4./s^2.*(w-wc);
            fsimder = fft(V).*fft(fftshift(gsder))*exp(-1i*phase0);
            dsim = real(ifft(fsimder))/max(real(ifft(fsimder)));
            [Mss, Nss] = max (dsim);
            [Minss, Ninss] = min (dsim);
            SmaxS = 0;
            SminS = 0;
            for qs = Nss-1:1:Nss+1
                SmaxS = SmaxS + dsim(qs);
            end
            for qmins = Ninss-1:1:Ninss+1
                SminS = SminS + dsim(qmins);
            end
            IntSpecS = SmaxS/3 - SminS/3;
            SimulSpec = dsim./IntSpecS;
            % rte = IntSpec/IntSpecS;
            % xmin = wH(1);
            % xmax = wH(length(Spectrumx));
            % ymin1 = min(SimulSpec);
            % ymin2 = min(Spectrumy);
            % ymin =min(ymin1, ymin2);
            % ymax1 = max(SimulSpec);
            % ymax2 = max(Spectrumy);
            % ymax =max(ymax1, ymax2);
            %subplot(2,1,1);
            %plot(wH, Spectrumy, "b");
            %axis([xmin xmax ymin ymax]) 
            %hold on
            %plot(wH, SimulSpec, "r");
            %residue  = Spectrumy - SimulSpec;
            %subplot(2,1,2);
            %plot(wH, residue);
            %RSS = sum(residue.^2)/sum(Spectrumy.^2)*100;
            %title (RSS)
            end
        end
        
        function [b,Y,simulated,resid,B,ci] = pTAMSpectralFitting(app, Spectrum, Xposition, XpositionRange, oxygenLW, oxygenLWRange, ...
                 ABDistance, ABDistanceRange, kExchange, kExchangeRange, Pa, PaRange, LWaZero, LWLaZeroRange, LWbZero, LWbZeroRange, LWG, LWGRange, phase_corr)
   
            %% pTAM fitting for two lines, using analytical solution
            % by Stephen DeVience and Andrey Bobko

            Flip = 1; % Spectrum Flip if necessary
            Filter = 0; % unused for now
            
            % SmoothSig = datasmooth(Inten1,Filter); % Smoothing, if desired. Not currently implemented. Depending
            % Matlab version, may need to use function smoothdata.m instead.
          
            Spectrum(:, 1) = Spectrum(:, 1)*1;
            Spectrum(:, 2) = 100*Flip*(Spectrum(:, 2)-mean(Spectrum(:, 2)));
            b=Spectrum(:,1);

            % Some processing
            cguess=Spectrum(round(length(Spectrum(:,1))/2) + Xposition,1);
            phase0=phase_corr*pi/180;

           
            % Guesses for lsqcurvefit
            guess1=[cguess oxygenLW ABDistance kExchange Pa LWaZero LWbZero LWG phase0];
            guess_range=[XpositionRange oxygenLWRange ABDistanceRange kExchangeRange PaRange LWLaZeroRange LWbZeroRange LWGRange 50*pi/180];
   
            %%
            % Intensity scaling
            [Ms, Ns] = max(Spectrum(:, 2));
            [Mins, Nins] = min(Spectrum(:, 2));
            SmaxV = 0;
            SminV = 0;
            for q = Ns-2:1:Ns+2
                SmaxV = SmaxV + Spectrum(q, 2);
            end
            for qmin = Nins-2:1:Nins+2
               SminV = SminV + Spectrum(qmin, 2);
            end
            IntSpec = SmaxV/5 - SminV/5;
            Y=Spectrum(:,2)/IntSpec;
        
            % Call fitting functions.
            options1=optimset("TolX",1e-9,"Display","iter-detailed");
            options2=optimset("MaxIter",100,"Display","iter");
            
            objfun = @(x,b) pTamSpeAuto(app, b,x(1),x(2),x(3),x(4),x(5),x(6),x(7),x(8),x(9));
            [B,resnorm,resid,exitflag,output,lambda,J] = lsqcurvefit(objfun,guess1,b,Y,guess1-guess_range,guess1+guess_range,options1);
             
            % Calculate simulated spectrum.
            simulated=pTamSpeAuto(app, b, real(B(1)),real(B(2)),real(B(3)),real(B(4)),real(B(5)),real(B(6)),real(B(7)),real(B(8)),real(B(9)));
            ci = nlparci(B,resid,"jacobian",J);
        
            %% Fitting parameters
            
            % These are the initial guesses for fitting, which will fill in the vector
            % "guess1" below. The vector "guess_range" defines the +/- limits for the
            % parameter search. So if the guess for oxygenLW is 200 and guess_range for
            % it is 200, the fitting will find a value somewhere between 0 and 400 for
            % oxygen. Be careful of the range for values that cannot go below 0. If
            % a non-symmetric range is desired, positive and negative boundaries can be
            % defined, with appropriate modification of the lsqcurvefit call further
            % below.
            
            % Xposition = Field position of EPR signal in points
            % oxygenLW = Lorentzian broadening due to oxygen, value in mG
            % ABDistance = % Distance between acidic and basic components in points, should be calculated from buffer-free solution and remain constant.
                            % Make this number negative if the acid peak is the lower field component.
                            % For pTAM, acid component is always the outer lines.
            % kExchange = Exchange rate (1/s)
            % Pa = Fraction of population for acid component.
            % phase_corr = Phase correction, degrees
            
            % Predetermined parameters of linewidths from calibration. These are
            % artificially set constant by making their range very small. If speed is
            % important and these will not change, they can be removed from the fitting
            % and set as constants in the function pTamSpeAuto.
            
            %LWaZero = Lorentzian P-P linewidth line a without oxygen, in mG
            %LWbZero = Lorentzian P-P linewidth line b without oxygen, in mG
            %LWG = Gaussian P-P linewidth for both lines, in mG
            
            %% Linewidth conversions
            
            % For Lorentzian:
            % FWHM = 1/(pi*T), where T is time constant, T_1, T_2, T_m depending on
            % situation
            % P-P linewidth from derivative spectrum = FWHM/sqrt(3)
            % P-P linewidth = 1/(pi*sqrt(3)*T)
        
            % For Gaussian:
            % FWHM = sigma*2*sqrt(2*ln(2)), where sigma is the standard deviation.
            % Inflection points are at +/- sigma, so P-P linewidth = 2*sigma.
        end
        
        %%Populate available calibrations
        function LoadCalibrations(app)
            try
                app.calibrations=readmatrix(app.calFile,'OutputType','string','NumHeaderLines',0,'Delimiter','|') ;
                if ~isempty(app.calibrations) && size(app.calibrations,2) ~= 9
                    app.calibrations = string.empty;
                end
                app.SelectCalibrationDropDown.Items = [strcat(app.calibrations(:,1)," ",app.calibrations(:,2)); "0 Custom/Calibrate"];
            catch 
                app.SelectCalibrationDropDown.Items = ["0 Custom/Calibrate"];
            end
        end
        
        %%Bring in parameters based on calibration
        function SetParameters(app, calibration)
        
            matched = 0;
           
            for n=1:size(app.calibrations,1)
                if (strcat(app.calibrations(n,1)," ",app.calibrations(n,2)) == calibration) % 2 - calibration name
                    matched = 1;
                    app.LWGStart.Value = str2double(app.calibrations(n,3)); % 3 - Gaussian linewideth (mG)
                    app.LWLaStart.Value = str2double(app.calibrations(n,4)); % 4 - Acidic Lorentzian linewideth (mG)
                    app.LWLbStart.Value = str2double(app.calibrations(n,5)); % 5 - Acidic Lorentzian linewideth (mG)
                    app.ABDistanceStart.Value = str2double(app.calibrations(n,6)); % 6 - A/B line distance (mG)
                    
                    % Calibration values
                    app.OxygenSlope.Value = str2double(app.calibrations(n,7)); % 7 - O2 line slope (mG/mmHg)
                    app.pKa.Value = str2double(app.calibrations(n,8)); % 8 - pKa
                    app.PhosphateSlope.Value = str2double(app.calibrations(n,9)); % 9 - Pi line slope (s-1/mM)
                    
                    EditableDisable(app);
                    CalibrateDisable(app);
                    
                    %set variable parameters
                    %vary O2 LW
                    app.OxygenLWStart.Value = 50;
                    app.OxygenLWSwitch.Value = "Vary";
                    app.OxygenLWRange.Enable = true;
                    app.OxygenLWRange.Value = 50;
                    
                    %vary acidic fraction
                    app.PaStartValue.Value = 0.5;
                    app.PaSwitch.Value = "Vary";
                    app.PaRange.Enable = true;
                    app.PaRange.Value = 0.5;
                    
                    %vary H exchange 
                    app.kExchangeStart.Value = 6e5;
                    app.kExchangeSwitch.Value = "Vary";
                    app.kExchangeRange.Enable = true;
                    app.kExchangeRange.Value = 6e5;     
                    
                end
            end
            if (matched == 0) % custom/calibrate
                EditableEnable(app);
                CalibrateEnable(app);
            end

            app.SaveChangesButton.Visible = false;
            app.SaveAsButton.Visible = false;
            app.DeleteCalButton.Visible = false;
            
        end
       
        function EditableEnable(app)
               %allow varying of "constants"
                app.LWGStart.Enable = true;
                app.LWLaStart.Enable = true;
                app.LWLbStart.Enable = true;
                app.ABDistanceStart.Enable = true;
                
                app.LWGSwitch.Enable = true;
                app.LWLaSwitch.Enable = true;
                app.LWLbSwitch.Enable = true;
                app.ABDistanceSwitch.Enable = true;
                
                %calibration slopes
                app.OxygenSlope.Editable = true;
                app.pKa.Editable = true;
                app.PhosphateSlope.Editable = true;
        end
        
        function EditableDisable(app)
            %Not Custom - cannot edit constants   
                app.LWGStart.Enable = false;
                app.LWLaStart.Enable = false;
                app.LWLbStart.Enable = false;
                app.ABDistanceStart.Enable = false;
                
                app.LWGSwitch.Value = "Constant";
                app.LWLaSwitch.Value = "Constant";
                app.LWLbSwitch.Value = "Constant";
                app.ABDistanceSwitch.Value = "Constant";
                
                app.LWGSwitch.Enable = false;
                app.LWLaSwitch.Enable = false;
                app.LWLbSwitch.Enable = false;
                app.ABDistanceSwitch.Enable = false;
                
                app.LWGRange.Enable = false;
                app.LWLaRange.Enable = false;
                app.LWLbRange.Enable = false;
                app.ABDistanceRange.Enable = false;
                
                app.LWGRange.Value = 0;
                app.LWLaRange.Value = 0;
                app.LWLbRange.Value = 0;
                app.ABDistanceRange.Value = 0;
                
                %calibration slopes
                app.OxygenSlope.Editable = false;
                app.pKa.Editable = false;
                app.PhosphateSlope.Editable = false;
        end
        
        function results = CalibrateEnable(app)
                %Calibrate buttons not available
                app.QuickCalButtonsPanel.Visible = true;
                app.SaveNewCalButton.Visible = true;
                
                %Edit Button not available
                app.EditCalCancelButton.Visible = false;
           
        end
        
        function results = CalibrateDisable(app)
            
                %Calibrate buttons not available
                app.QuickCalButtonsPanel.Visible = false;
                app.SaveNewCalButton.Visible = false;
                    
                %Edit Button available
                app.EditCalCancelButton.Visible = true;
                app.EditCalCancelButton.Text = "Edit Cal";
                
        end
        
        function Spectrum = LoadSpectrumFromFile(app)
           
            if(contains(lower(app.SpectrumFileName.Value),".ask")>0)
                %% Set up the Import Options and import the data
                opts = delimitedTextImportOptions("NumVariables", 8);
                
                % Specify range and delimiter
                opts.DataLines = [36, Inf];
                opts.Delimiter = ",";
                
                % Specify column names and types
                opts.VariableNames = ["DataFieldGaussX", "YInPhase"];
                opts.SelectedVariableNames = ["DataFieldGaussX", "YInPhase"];
                opts.VariableTypes = ["double", "double"];
                
                % Specify file level properties
                opts.ExtraColumnsRule = "ignore";
                opts.EmptyLineRule = "read";
                
                % Import the data
                ClinEPR = readtable(strcat(app.SpectrumFilePath.Value,app.SpectrumFileName.Value), opts);
                Spectrum=table2array(ClinEPR);
                
                %% Clear temporary variables
                clear opts
    
           %easyspin compatible
            else
                [B0,spc] = eprload1(strcat(app.SpectrumFilePath.Value,app.SpectrumFileName.Value));
                Spectrum = [B0,spc];
           end

        end % end loadspectrumfromfile

    end %end methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            calFileName = 'pTAM_cal.txt';
            
            %get file location for pTAM_cal location
            if isdeployed % Stand-alone mode.
                %Get executable file location
                [status, result] = system('path');
                exeDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                
                %check if pTAM_cal.txt in exe location is writeable
                exeCalFile = fullfile(exeDir,calFileName);
                %exeCalFile = 'C:\Program Files\pTAM_Fitting_App\application\pTAM_cal.txt';
                [fid, errmsg] = fopen(exeCalFile, 'a');
                
                %if not, check for file in home directory
                if strcmp(errmsg,'Permission denied')
                    %get home directory
                    if ispc()
                        homeDir = [getenv('HOMEDRIVE'),getenv('HOMEPATH')];
                    else
                        homeDir = getenv('HOME');
                    end
                    %if no home directory, use deployable archive 
                    if isempty(homeDir)
                        homeDir = ctfroot();
                    end
                    %if a copy of the file in homeDir does not exist
                    if ~isfile(fullfile(homeDir,'pTAM_Fitting_App',calFileName))
                        mkdir(homeDir,'pTAM_Fitting_App');
                        %if exe directory file does exist, copy to homeDir
                        if isfile(exeCalFile)
                            copyfile(exeCalFile,fullfile( homeDir,'pTAM_Fitting_App',calFileName));
                        end
                    end
                    %make main location the homeDir folder
                    calFileDir = fullfile( homeDir,'pTAM_Fitting_App');
                else
                    %if permission not denied, use location of exe
                    fclose(fid);
                    calFileDir = exeDir;
                end
            else % MATLAB developer mode.
                calFileDir = fileparts(mfilename('fullpath'));
            end
            %full file location and name of saved cal location
            app.calFile = fullfile(calFileDir,calFileName);
            %Get calibrations from save file (pTAM_cal.txt)
            LoadCalibrations(app);
            %select first option
            app.SelectCalibrationDropDown.Value = app.SelectCalibrationDropDown.Items(1);
            %set parameters based on calibration selected
            calibration = app.SelectCalibrationDropDown.Value;
            SetParameters(app,calibration);
        end

        % Button pushed function: FitSpectrumButton
        function FitSpectrumButtonPushed(app, event)

            % Set parameters
            XpositionRangeA = 500;                              % adjust center field in points
            OxygenLWRangeA = app.OxygenLWRange.Value;           % line broadening by oxygen (mG)
            ABDistanceRangeA = app.ABDistanceRange.Value;   % distance between acidic and basic peaks without buffer (mG)
            kExchangeRangeA = app.kExchangeRange.Value;         % proton exchange rate (Hz or 1/s)
            PaRangeA = app.PaRange.Value;                       % acidic species fraction
            LWaZeroRangeA = app.LWLaRange.Value;             % Lorentzian P-P linewidth acidic line without oxygen (mG)
            LWbZeroRangeA = app.LWLbRange.Value;             % Lorentzian P-P linewidth basic line without oxygen (mG)
            LWGRangeA = app.LWGRange.Value;                     % Gaussian P-P linewidth for both lines (mG)
            
            % If set to constant, Set Range to 0
            if(strcmp(app.OxygenLWSwitch.Value,"Constant") == 1) 
                OxygenLWRangeA = 0; end
            if(strcmp(app.ABDistanceSwitch.Value,"Constant") == 1) 
                ABDistanceRangeA = 0; end
            if(strcmp(app.kExchangeSwitch.Value,"Constant") == 1) 
                kExchangeRangeA = 0; end
            if(strcmp(app.PaSwitch.Value,"Constant") == 1) 
                PaRangeA = 0; end
            if(strcmp(app.LWLaSwitch.Value,"Constant") == 1) 
                LWaZeroRangeA = 0; end
            if(strcmp(app.LWLbSwitch.Value,"Constant") == 1) 
                LWbZeroRangeA = 0; end
            if(strcmp(app.LWGSwitch.Value,"Constant") == 1) 
                LWGRangeA = 0; end
            
            % Load file and make matrix named Spectrum
            try
                Spectrum = LoadSpectrumFromFile(app);
                if(app.FieldSwitch.Value == "mT")
                    Spectrum(:, 1) = Spectrum(:, 1)*10;
                end
                
                try
                    % Pass Spectrum and all paramters and ranges to fitting function
                    % Return spectrum, simulation, residual, and best fit results
                    [b,Y,simulated,resid,B,ci] = pTAMSpectralFitting(app, Spectrum, app.XpositionStart.Value, XpositionRangeA, ...
                    app.OxygenLWStart.Value, OxygenLWRangeA, app.ABDistanceStart.Value, ABDistanceRangeA, ...
                    app.kExchangeStart.Value, kExchangeRangeA, app.PaStartValue.Value, PaRangeA, app.LWLaStart.Value, LWaZeroRangeA, ...
                    app.LWLbStart.Value, LWbZeroRangeA, app.LWGStart.Value, LWGRangeA, app.PhaseCorr.Value);
                
                    % Plot spectrum, simulation, and residual
                    plot(app.spectrum_simulation,b,Y,"b",b,simulated,"r");
                    plot(app.spectrum_fitting,b,resid);
                   
                    % Output best fit parameters and their confidence intervals.
                    app.CenterResult.Value = B(1,1);
                    app.OxygenLWResult.Value = B(1,2);
                    app.ABDistanceResult.Value = B(1,3);
                    app.kExchangeResult.Value = B(1,4);
                    app.PaResult.Value = B(1,5);
                    app.LWLaResult.Value = B(1,6);
                    app.LWLbResult.Value = B(1,7);
                    app.LWGResult.Value = B(1,8);   
                    app.PhaseResult.Value = B(1,9)*180/pi; %convert radians to degrees
                catch
                    errordlg("Error in Parameters/Fitting","Fitting Error");
                end
                
                try
                    app.OxygenResult.Value =  app.OxygenLWResult.Value / app.OxygenSlope.Value;
                    app.pHResult.Value = app.pKa.Value + log10((1-app.PaResult.Value)/app.PaResult.Value);
                    app.PiTermResult.Value = app.kExchangeResult.Value*(10^(-1*app.pHResult.Value)+10^-6.66)/10^-6.66/2/pi;
                    app.PhosphateResult.Value = app.PiTermResult.Value/app.PhosphateSlope.Value;
                    if(app.PhosphateResult.Value < 0) app.PhosphateResult.Value = 0; end
                catch 
                    errordlg("Error in Calibration Values","Calibration Error");
                end
            catch
                % If Spectrum data could not load, display error
                errordlg("File not found or Spectrum could not load","File Error"); 
            end
            
  
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            %Select file and retrieve file path and name
            [FileName,PathName] = uigetfile("*.DSC;*.DTA;*.SPE;*.ASK","Select the Spectrum data file",app.SpectrumFilePath.Value);
            figure(app.UIFigure);   %refocus window to front
            
            %if file was selected, load file name and path
            if(FileName~=0)
                app.SpectrumFileName.Value = FileName;
                app.SpectrumFilePath.Value = PathName;
                %try to read ClinEPR ASK file or DSC/DTA/SPE file by easyspin EPRload function
                try
                    Spectrum = LoadSpectrumFromFile(app);
                    plot(app.spectrum_simulation,Spectrum(:,1),Spectrum(:,2));
                    cla(app.spectrum_fitting);
                catch
                    %if file cannot be read, display error 
                    errordlg("Spectrum file could not load","File Error"); 
                end    

                %guess field unit
                if(contains(lower(FileName),".spe")>0)
                    app.FieldSwitch.Value = "mT";
                else
                    app.FieldSwitch.Value = "G";
                end
                
                app.OxygenResult.Value = 0;
                app.pHResult.Value = 0;
                app.PhosphateResult.Value = 0;
                
                app.LWGResult.Value = 0;
                app.LWLaResult.Value = 0;
                app.LWLbResult.Value = 0;
                app.ABDistanceResult.Value = 0;
                app.OxygenLWResult.Value = 0;
                app.PaResult.Value = 0;
                app.kExchangeResult.Value = 0;
                app.PiTermResult.Value = 0;
                app.CenterResult.Value = 0;
                app.PhaseResult.Value = 0;
            
            end
        end

        % Value changed function: LWGSwitch
        function LWGSwitchValueChanged(app, event)
            switch app.LWGSwitch.Value
                case "Vary" 
                    app.LWGRange.Enable=true;
                    if( app.LWGRange.Value > app.LWGStart.Value) 
                        app.LWGRange.Value = app.LWGStart.Value;
                    end
                case "Constant"
                    app.LWGRange.Enable=false;
            end
        end

        % Value changed function: LWLaSwitch
        function LWLaSwitchValueChanged(app, event)
            switch app.LWLaSwitch.Value
                case "Vary" 
                    app.LWLaRange.Enable=true;
                    if( app.LWLaRange.Value > app.LWLaStart.Value) 
                        app.LWLaRange.Value = app.LWLaStart.Value;
                    end
                case "Constant"
                    app.LWLaRange.Enable=false;
            end
        end

        % Value changed function: LWLbSwitch
        function LWLbSwitchValueChanged(app, event)
            switch app.LWLbSwitch.Value
                case "Vary" 
                    app.LWLbRange.Enable=true;
                    if( app.LWLbRange.Value > app.LWLbStart.Value) 
                        app.LWLbRange.Value = app.LWLbStart.Value;
                    end
                case "Constant"
                    app.LWLbRange.Enable=false;
            end
        end

        % Value changed function: ABDistanceSwitch
        function ABDistanceSwitchValueChanged(app, event)
            switch app.ABDistanceSwitch.Value
                case "Vary" 
                    app.ABDistanceRange.Enable=true;
                    if( app.ABDistanceRange.Value > abs(app.ABDistanceStart.Value)) 
                        app.ABDistanceRange.Value = abs(app.ABDistanceStart.Value);
                    end
                case "Constant"
                    app.ABDistanceRange.Enable=false;
            end
        end

        % Value changed function: kExchangeSwitch
        function kExchangeSwitchValueChanged(app, event)
            switch app.kExchangeSwitch.Value
                case "Vary" 
                    app.kExchangeRange.Enable=true;
                    if( app.kExchangeRange.Value > app.kExchangeStart.Value) 
                        app.kExchangeRange.Value = app.kExchangeStart.Value;
                    end
                case "Constant"
                    app.kExchangeRange.Enable=false;
            end
        end

        % Value changed function: PaSwitch
        function PaSwitchValueChanged(app, event)
            switch app.PaSwitch.Value
                case "Vary" 
                    app.PaRange.Enable=true;
                    if( app.PaRange.Value > app.PaStartValue.Value) 
                        app.PaRange.Value = app.PaStartValue.Value;
                    end
                case "Constant"
                    app.PaRange.Enable=false;
            end  
        end

        % Value changed function: OxygenLWSwitch
        function OxygenLWSwitchValueChanged(app, event)
            switch app.OxygenLWSwitch.Value
                case "Vary" 
                    app.OxygenLWRange.Enable=true;
                    if( app.OxygenLWRange.Value > app.OxygenLWStart.Value) 
                        app.OxygenLWRange.Value = app.OxygenLWStart.Value;
                    end
                case "Constant"
                    app.OxygenLWRange.Enable=false;
            end  
        end

        % Value changed function: SelectCalibrationDropDown
        function SelectCalibrationDropDownValueChanged(app, event)
            calibration = app.SelectCalibrationDropDown.Value;
            SetParameters(app, calibration);
        end

        % Value changed function: OxygenLWRange
        function OxygenLWRangeValueChanged(app, event)
            if( app.OxygenLWRange.Value > app.OxygenLWStart.Value) 
                app.OxygenLWRange.Value = app.OxygenLWStart.Value;
            end
        end

        % Value changed function: OxygenLWStart
        function OxygenLWStartValueChanged(app, event)
            if( app.OxygenLWRange.Value > app.OxygenLWStart.Value) 
                app.OxygenLWRange.Value = app.OxygenLWStart.Value;
            end
        end

        % Value changed function: PaStartValue
        function PaStartValueValueChanged(app, event)
            if( app.PaRange.Value > app.PaStartValue.Value) 
                app.PaRange.Value = app.PaStartValue.Value;
            end
        end

        % Value changed function: PaRange
        function PaRangeValueChanged(app, event)
            if( app.PaRange.Value > app.PaStartValue.Value) 
                app.PaRange.Value = app.PaStartValue.Value;
            end
        end

        % Value changed function: kExchangeStart
        function kExchangeStartValueChanged(app, event)
            if( app.kExchangeRange.Value > app.kExchangeStart.Value) 
                app.kExchangeRange.Value = app.kExchangeStart.Value;
            end
        end

        % Value changed function: kExchangeRange
        function kExchangeRangeValueChanged(app, event)
            if( app.kExchangeRange.Value > app.kExchangeStart.Value) 
                app.kExchangeRange.Value = app.kExchangeStart.Value;
            end
        end

        % Value changed function: ABDistanceRange
        function ABDistanceRangeValueChanged(app, event)
            if( app.ABDistanceRange.Value > abs(app.ABDistanceStart.Value)) 
                app.ABDistanceRange.Value = abs(app.ABDistanceStart.Value);
            end
        end

        % Value changed function: ABDistanceStart
        function ABDistanceStartValueChanged(app, event)
            if( app.ABDistanceRange.Value > abs(app.ABDistanceStart.Value)) 
                app.ABDistanceRange.Value = abs(app.ABDistanceStart.Value);
            end
        end

        % Value changed function: LWLbRange
        function LWLbRangeValueChanged(app, event)
            if( app.LWLbRange.Value > app.LWLbStart.Value) 
                app.LWLbRange.Value = app.LWLbStart.Value;
            end
        end

        % Value changed function: LWLbStart
        function LWLbStartValueChanged(app, event)
            if( app.LWLbRange.Value > app.LWLbStart.Value) 
                app.LWLbRange.Value = app.LWLbStart.Value;
            end
        end

        % Value changed function: LWLaRange
        function LWLaRangeValueChanged(app, event)
            if( app.LWLaRange.Value > app.LWLaStart.Value) 
                app.LWLaRange.Value = app.LWLaStart.Value;
            end
        end

        % Value changed function: LWLaStart
        function LWLaStartValueChanged(app, event)
            if( app.LWLaRange.Value > app.LWLaStart.Value) 
                app.LWLaRange.Value = app.LWLaStart.Value;
            end
        end

        % Value changed function: LWGRange
        function LWGRangeValueChanged(app, event)
            if( app.LWGRange.Value > app.LWGStart.Value) 
                app.LWGRange.Value = app.LWGStart.Value;
            end
        end

        % Value changed function: LWGStart
        function LWGStartValueChanged(app, event)
            if( app.LWGRange.Value > app.LWGStart.Value) 
                app.LWGRange.Value = app.LWGStart.Value;
            end
        end

        % Button pushed function: pH5LWButton
        function pH5LWButtonPushed(app, event)
            %Find LW Gaussian and acidic LW lorentzian at 0% O2, pH 4-5
            %allow LW Guass to vary
            app.LWGSwitch.Value = "Vary";
            app.LWGRange.Enable = true;
            if(app.LWGStart.Value < 5) app.LWGStart.Value = 5; end
            app.LWGRange.Value =  app.LWGStart.Value;
            
            %allow LW A Lorent to vary
            app.LWLaSwitch.Value = "Vary";
            app.LWLaRange.Enable = true;
            if(app.LWLaStart.Value < 5) app.LWLaStart.Value = 5; end
            app.LWLaRange.Value = app.LWLaStart.Value;
            
            %hold LW B Lorent constant
            app.LWLbSwitch.Value = "Constant";
            app.LWLbRange.Enable = false;
            app.LWLbRange.Value = 0;
            
            %hold O2 LW at 0
            app.OxygenLWStart.Value = 0;
            app.OxygenLWSwitch.Value = "Constant";
            app.OxygenLWRange.Enable = false;
            app.OxygenLWRange.Value = 0;
            
            %hold acidic fraction at 1
            app.PaStartValue.Value = 1;
            app.PaSwitch.Value = "Constant";
            app.PaRange.Enable = false;
            app.PaRange.Value = 0;
            
            %vary H exchange 
            app.kExchangeStart.Value = 6e5;
            app.kExchangeSwitch.Value = "Vary";
            app.kExchangeRange.Enable = true;
            app.kExchangeRange.Value = 6e5; 
        end

        % Button pushed function: pH5O2CalButton
        function pH5O2CalButtonPushed(app, event)
            %Find O2 LW at known pO2, pH 4-5
            %hold LW Guass constant
            app.LWGSwitch.Value = "Constant";
            app.LWGRange.Enable = false;
            app.LWGRange.Value = 0;
            
            %hold LW A Lorent constant
            app.LWLaSwitch.Value = "Constant";
            app.LWLaRange.Enable = false;
            app.LWLaRange.Value = 0;
            
            %hold LW B Lorent constant
            app.LWLbSwitch.Value = "Constant";
            app.LWLbRange.Enable = false;
            app.LWLbRange.Value = 0;
            
            %hold AB distance constant
            app.ABDistanceSwitch.Value = "Constant";
            app.ABDistanceRange.Enable = false;
            app.ABDistanceRange.Value = 0;
            
            %vary O2 LW
            app.OxygenLWStart.Value = 50;
            app.OxygenLWSwitch.Value = "Vary";
            app.OxygenLWRange.Enable = true;
            app.OxygenLWRange.Value = 50;
            
            %hold acidic fraction at 1
            app.PaStartValue.Value = 1;
            app.PaSwitch.Value = "Constant";
            app.PaRange.Enable = false;
            app.PaRange.Value = 0;
            
            %vary H exchange 
            app.kExchangeStart.Value = 6e5;
            app.kExchangeSwitch.Value = "Vary";
            app.kExchangeRange.Enable = true;
            app.kExchangeRange.Value = 6e5;               
        end

        % Button pushed function: pH10LWButton
        function pH10LWButtonPushed(app, event)
            %Find LW Gaussian and basic LW lorentzian at 0% O2, pH 10+
            %allow LW Guass to vary
            app.LWGSwitch.Value = "Vary";
            app.LWGRange.Enable = true;
            if(app.LWGStart.Value < 5); app.LWGStart.Value = 5; end
            app.LWGRange.Value =  app.LWGStart.Value;
            
            %hold LW A Lorent constant
            app.LWLaSwitch.Value = "Constant";
            app.LWLaRange.Enable = false;
            app.LWLaRange.Value = 0;
            
            %allow LW B Lorent to vary
            app.LWLbSwitch.Value = "Vary";
            app.LWLbRange.Enable = true;
            if(app.LWLbStart.Value < 5) app.LWLbStart.Value = 5; end
            app.LWLbRange.Value = app.LWLbStart.Value;
            
            %hold O2 LW at 0
            app.OxygenLWStart.Value = 0;
            app.OxygenLWSwitch.Value = "Constant";
            app.OxygenLWRange.Enable = false;
            app.OxygenLWRange.Value = 0;
            
            %hold acidic fraction at 0
            app.PaStartValue.Value = 0;
            app.PaSwitch.Value = "Constant";
            app.PaRange.Enable = false;
            app.PaRange.Value = 0;
            
            %vary H exchange 
            app.kExchangeStart.Value = 6e5;
            app.kExchangeSwitch.Value = "Vary";
            app.kExchangeRange.Enable = true;
            app.kExchangeRange.Value = 6e5;            
        end

        % Button pushed function: pH10O2CalButton
        function pH10O2CalButtonPushed(app, event)
            %Find O2 LW at known pO2, pH 10+
            %hold LW Guass constant
            app.LWGSwitch.Value = "Constant";
            app.LWGRange.Enable = false;
            app.LWGRange.Value = 0;
            
            %hold LW A Lorent constant
            app.LWLaSwitch.Value = "Constant";
            app.LWLaRange.Enable = false;
            app.LWLaRange.Value = 0;
            
            %hold LW B Lorent constant
            app.LWLbSwitch.Value = "Constant";
            app.LWLbRange.Enable = false;
            app.LWLbRange.Value = 0;
            
            %hold AB distance constant
            app.ABDistanceSwitch.Value = "Constant";
            app.ABDistanceRange.Enable = false;
            app.ABDistanceRange.Value = 0;
            
            %hold O2 LW at 0
            app.OxygenLWStart.Value = 50;
            app.OxygenLWSwitch.Value = "Vary";
            app.OxygenLWRange.Enable = true;
            app.OxygenLWRange.Value = 50;
            
            %hold acidic fraction at 0
            app.PaStartValue.Value = 0;
            app.PaSwitch.Value = "Constant";
            app.PaRange.Enable = false;
            app.PaRange.Value = 0;     
            
            %vary H exchange 
            app.kExchangeStart.Value = 6e5;
            app.kExchangeSwitch.Value = "Vary";
            app.kExchangeRange.Enable = true;
            app.kExchangeRange.Value = 6e5;
        end

        % Button pushed function: pH7LWButton
        function pH7LWButtonPushed(app, event)
            %Find LW Gaussian and both LW lorentzian at 0% O2, pH ~7
            %allow LW Guass to vary
            app.LWGSwitch.Value = "Vary";
            app.LWGRange.Enable = true;
            if(app.LWGStart.Value < 5) app.LWGStart.Value = 5; end
            app.LWGRange.Value =  app.LWGStart.Value;
            
            %allow LW A Lorent to vary
            app.LWLaSwitch.Value = "Vary";
            app.LWLaRange.Enable = true;
            if(app.LWLaStart.Value < 5) app.LWLaStart.Value = 5; end
            app.LWLaRange.Value = app.LWLaStart.Value;
            
            %allow LW B Lorent to vary
            app.LWLbSwitch.Value = "Vary";
            app.LWLbRange.Enable = true;
            app.LWLbRange.Value
            if(app.LWLbStart.Value < 5) app.LWLbStart.Value = 5; end
            app.LWLbRange.Value = app.LWLbStart.Value;
            
            %hold O2 LW at 0
            app.OxygenLWStart.Value = 0;
            app.OxygenLWSwitch.Value = "Constant";
            app.OxygenLWRange.Enable = false;
            app.OxygenLWRange.Value = 0;
            
            %allow AB distance to vary
            app.ABDistanceSwitch.Value = "Vary";
            app.ABDistanceRange.Enable = true;
            if (app.ABDistanceStart.Value < 10 && app.ABDistanceStart.Value >= 0 ) app.ABDistanceStart.Value = 10; end
            if (app.ABDistanceStart.Value > -10 && app.ABDistanceStart.Value < 0 ) app.ABDistanceStart.Value = -10; end
            app.ABDistanceRange.Value = abs(app.ABDistanceStart.Value);
            
            %vary acidic fraction around 0.5
            app.PaStartValue.Value = 0.5;
            app.PaSwitch.Value = "Vary";
            app.PaRange.Enable = true;
            app.PaRange.Value = 0.5;        
            
            %hold H+Exchange at 1e-5
            app.kExchangeStart.Value = 1e-5;
            app.kExchangeSwitch.Value = "Constant";
            app.kExchangeRange.Enable = false;
            app.kExchangeRange.Value = 0;
        end

        % Button pushed function: pH7O2CalButton
        function pH7O2CalButtonPushed(app, event)
            %Find O2 LW at known pO2, pH~7
            %hold LW Guass constant
            app.LWGSwitch.Value = "Constant";
            app.LWGRange.Enable = false;
            app.LWGRange.Value = 0;
            
            %hold LW A Lorent constant
            app.LWLaSwitch.Value = "Constant";
            app.LWLaRange.Enable = false;
            app.LWLaRange.Value = 0;
            
            %hold LW B Lorent constant
            app.LWLbSwitch.Value = "Constant";
            app.LWLbRange.Enable = false;
            app.LWLbRange.Value = 0;
            
            %hold AB distance constant
            app.ABDistanceSwitch.Value = "Constant";
            app.ABDistanceRange.Enable = false;
            app.ABDistanceRange.Value = 0;
            
            %hold O2 LW at 0
            app.OxygenLWStart.Value = 50;
            app.OxygenLWSwitch.Value = "Vary";
            app.OxygenLWRange.Enable = true;
            app.OxygenLWRange.Value = 50;
            
            %vary acidic fraction around 0.5
            app.PaStartValue.Value = 0.5;
            app.PaSwitch.Value = "Vary";
            app.PaRange.Enable = true;
            app.PaRange.Value = 0.5;   
            
            %hold H+Exchange at 1e-5
            app.kExchangeStart.Value = 1e-5;
            app.kExchangeSwitch.Value = "Constant";
            app.kExchangeRange.Enable = false;
            app.kExchangeRange.Value = 0;
        end

        % Button pushed function: PiDistButton
        function PiDistButtonPushed(app, event)
            %Find distance between A and B peaks when [Pi] = 0mM
            %hold LW Guass constant
            app.LWGSwitch.Value = "Constant";
            app.LWGRange.Enable = false;
            app.LWGRange.Value = 0;
            
            %hold LW A Lorent constant
            app.LWLaSwitch.Value = "Constant";
            app.LWLaRange.Enable = false;
            app.LWLaRange.Value = 0;
            
            %hold LW B Lorent constant
            app.LWLbSwitch.Value = "Constant";
            app.LWLbRange.Enable = false;
            app.LWLbRange.Value = 0;
            
            %allow AB distance to vary
            app.ABDistanceSwitch.Value = "Vary";
            app.ABDistanceRange.Enable = true;
            if (app.ABDistanceStart.Value < 10 && app.ABDistanceStart.Value >= 0 ) app.ABDistanceStart.Value = 10; end
            if (app.ABDistanceStart.Value > -10 && app.ABDistanceStart.Value < 0 ) app.ABDistanceStart.Value = -10; end
            app.ABDistanceRange.Value = abs(app.ABDistanceStart.Value);
            
            %hold O2 LW at 0
            app.OxygenLWStart.Value = 0;
            app.OxygenLWSwitch.Value = "Constant";
            app.OxygenLWRange.Enable = false;
            app.OxygenLWRange.Value = 0;
            
            %hold H exchange at 1e-5
            app.kExchangeStart.Value = 1e-5;
            app.kExchangeSwitch.Value = "Constant";
            app.kExchangeRange.Enable = false;
            app.kExchangeRange.Value = 0;
            
            %vary acidic fraction around 0.5
            app.PaStartValue.Value = 0.5;
            app.PaSwitch.Value = "Vary";
            app.PaRange.Enable = true;
            app.PaRange.Value = 0.5;
        end

        % Button pushed function: PiCalButton
        function PiCalButtonPushed(app, event)
            %Find kExchnage at known [Pi]
            %hold LW Guass constant
            app.LWGSwitch.Value = "Constant";
            app.LWGRange.Enable = false;
            app.LWGRange.Value = 0;
            
            %hold LW A Lorent constant
            app.LWLaSwitch.Value = "Constant";
            app.LWLaRange.Enable = false;
            app.LWLaRange.Value = 0;
            
            %hold LW B Lorent constant
            app.LWLbSwitch.Value = "Constant";
            app.LWLbRange.Enable = false;
            app.LWLbRange.Value = 0;
            
            %hold AB distance constant
            app.ABDistanceSwitch.Value = "Constant";
            app.ABDistanceRange.Enable = false;
            app.ABDistanceRange.Value = 0;
            
            %hold O2 LW at 0
            app.OxygenLWStart.Value = 0;
            app.OxygenLWSwitch.Value = "Constant";
            app.OxygenLWRange.Enable = false;
            app.OxygenLWRange.Value = 0;
            
            %vary H exchange 
            app.kExchangeStart.Value = 6e5;
            app.kExchangeSwitch.Value = "Vary";
            app.kExchangeRange.Enable = true;
            app.kExchangeRange.Value = 6e5;
            
            %vary acidic fraction around 0.5
            app.PaStartValue.Value = 0.5;
            app.PaSwitch.Value = "Vary";
            app.PaRange.Enable = true;
            app.PaRange.Value = 0.5;                        
        end

        % Button pushed function: LineFitting
        function LineFittingButtonPushed(app, event)
            %Find acidic fraction at known pH
            %hold LW Guass constant
            app.LWGSwitch.Value = "Constant";
            app.LWGRange.Enable = false;
            app.LWGRange.Value = 0;
            
            %hold LW A Lorent constant
            app.LWLaSwitch.Value = "Constant";
            app.LWLaRange.Enable = false;
            app.LWLaRange.Value = 0;
            
            %hold LW B Lorent constant
            app.LWLbSwitch.Value = "Constant";
            app.LWLbRange.Enable = false;
            app.LWLbRange.Value = 0;
            
            %hold AB distance constant
            app.ABDistanceSwitch.Value = "Constant";
            app.ABDistanceRange.Enable = false;
            app.ABDistanceRange.Value = 0;
            
            %vary O2 LW 
            app.OxygenLWStart.Value = 50;
            app.OxygenLWSwitch.Value = "Vary";
            app.OxygenLWRange.Enable = true;
            app.OxygenLWRange.Value = 50;
            
            %vary H exchange 
            app.kExchangeStart.Value = 6e5;
            app.kExchangeSwitch.Value = "Vary";
            app.kExchangeRange.Enable = true;
            app.kExchangeRange.Value = 6e5;
            
            %vary acidic fraction around 0.5
            app.PaStartValue.Value = 0.5;
            app.PaSwitch.Value = "Vary";
            app.PaRange.Enable = true;
            app.PaRange.Value = 0.5;           
        end

        % Button pushed function: pKaCalButton
        function pKaCalButtonPushed(app, event)
            %Find acidic fraction at known pH
            %hold LW Guass constant
            app.LWGSwitch.Value = "Constant";
            app.LWGRange.Enable = false;
            app.LWGRange.Value = 0;
            
            %hold LW A Lorent constant
            app.LWLaSwitch.Value = "Constant";
            app.LWLaRange.Enable = false;
            app.LWLaRange.Value = 0;
            
            %hold LW B Lorent constant
            app.LWLbSwitch.Value = "Constant";
            app.LWLbRange.Enable = false;
            app.LWLbRange.Value = 0;
            
            %hold AB distance constant
            app.ABDistanceSwitch.Value = "Constant";
            app.ABDistanceRange.Enable = false;
            app.ABDistanceRange.Value = 0;
            
            %hold O2 LW at 0
            app.OxygenLWStart.Value = 0;
            app.OxygenLWSwitch.Value = "Constant";
            app.OxygenLWRange.Enable = false;
            app.OxygenLWRange.Value = 0;
            
            %vary H exchange 
            app.kExchangeStart.Value = 6e5;
            app.kExchangeSwitch.Value = "Vary";
            app.kExchangeRange.Enable = true;
            app.kExchangeRange.Value = 6e5;
            
            %vary acidic fraction around 0.5
            app.PaStartValue.Value = 0.5;
            app.PaSwitch.Value = "Vary";
            app.PaRange.Enable = true;
            app.PaRange.Value = 0.5;            
        end

        % Button pushed function: SaveNewCalButton
        function SaveNewCalButtonPushed(app, event)

            %Prompt for new calibration name
            calname = inputdlg('Enter calibration name (blank will cancel save)','Calibration Name');

            %if calname empty or user hits cancel, skip code
            if ~isempty(calname) && ~isempty(calname{1,1})
                newIndex = num2str(size(app.calibrations,1)+1);
                calibration = strcat(newIndex," ",calname);
                
                %collect all calibration values
                newcal = [num2str(newIndex) calname num2str(app.LWGStart.Value) num2str(app.LWLaStart.Value) num2str(app.LWLbStart.Value) num2str(app.ABDistanceStart.Value) num2str(app.OxygenSlope.Value) num2str(app.pKa.Value) num2str(app.PhosphateSlope.Value)];
    
                %append to calibration list
                app.calibrations = [app.calibrations; newcal];
                
                %save to txt file
                writematrix(app.calibrations,app.calFile,'Delimiter','|');
                
                %reload calibrations
                LoadCalibrations(app);
                app.SelectCalibrationDropDown.Value = calibration;
                SetParameters(app,calibration);
            end
        end

        % Button pushed function: EditCalCancelButton
        function EditCalCancelButtonPushed(app, event)
            if app.EditCalCancelButton.Text == "Edit Cal"
                EditableEnable(app);
                app.EditCalCancelButton.Text = "Cancel";
                app.SaveChangesButton.Visible = true;
                app.SaveAsButton.Visible = true;
                app.DeleteCalButton.Visible = true;

            else
                EditableDisable(app);
                app.EditCalCancelButton.Text = "Edit Cal";
                app.SaveChangesButton.Visible = false;
                app.SaveAsButton.Visible = false;
                app.DeleteCalButton.Visible = false;
            end
            
        end

        % Button pushed function: SaveChangesButton
        function SaveChangesButtonPushed(app, event)
            
            %sep index and name
            index = extractBefore(app.SelectCalibrationDropDown.Value," ");
            calname = extractAfter(app.SelectCalibrationDropDown.Value," ");

            %Prompt for new calibration name
            calname = inputdlg('Calibration Name (blank will cancel save):','Calibration Name',[1 35],{calname});
            
            %if calname empty or user hits cancel, skip code
            if ~isempty(calname) && ~isempty(calname{1,1})
                %update in calibration list
                updatedcal = [num2str(index) calname num2str(app.LWGStart.Value) num2str(app.LWLaStart.Value) num2str(app.LWLbStart.Value) num2str(app.ABDistanceStart.Value) num2str(app.OxygenSlope.Value) num2str(app.pKa.Value) num2str(app.PhosphateSlope.Value)];
                 calibration = strcat(index," ",calname);
                
                app.calibrations(str2double(index),:) = updatedcal;
                %save to txt file
                writematrix(app.calibrations,app.calFile,'Delimiter','|');
                
                %reload calibrations
                LoadCalibrations(app);
                app.SelectCalibrationDropDown.Value = calibration;
                SetParameters(app,calibration);
            end
            
            
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            %Prompt for new calibration name
            calname = inputdlg('Enter calibration name (blank will cancel save)','Calibration Name');
            %if calname empty or user hits cancel, skip code
            if ~isempty(calname) && ~isempty(calname{1,1})
                newIndex = num2str(size(app.calibrations,1)+1);
                calibration = strcat(newIndex," ",calname);
                
                %collect all calibration values
                newcal = [num2str(newIndex) calname num2str(app.LWGStart.Value) num2str(app.LWLaStart.Value) num2str(app.LWLbStart.Value) num2str(app.ABDistanceStart.Value) num2str(app.OxygenSlope.Value) num2str(app.pKa.Value) num2str(app.PhosphateSlope.Value)];
    
                %append to calibration list
                app.calibrations = [app.calibrations; newcal];
                
                %save to txt file
                writematrix(app.calibrations,app.calFile,'Delimiter','|');
                
                %reload calibrations
                LoadCalibrations(app);
                app.SelectCalibrationDropDown.Value = calibration;
                SetParameters(app,calibration);
            end
        end

        % Button pushed function: DeleteCalButton
        function DeleteCalButtonPushed(app, event)
            answer = questdlg('Are you sure you would like to delete this calibration?', 'Are you sure?', ...
	            'Yes','No','No');
            
            switch answer
                case 'Yes'
                    %sep index and name
                    index = extractBefore(app.SelectCalibrationDropDown.Value," ");
                    app.calibrations(str2double(index),:) = [];
                    for n=1:size(app.calibrations,1)
                            app.calibrations(n,1) = n;
                    end
                case 'No'
            end
            
            %save to txt file
            writematrix(app.calibrations,app.calFile,'Delimiter','|');
            
            %reload calibrations
            LoadCalibrations(app);
            calibration = app.SelectCalibrationDropDown.Value;
            SetParameters(app,calibration);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.spectrum_simulation = uiaxes(app.UIFigure);
            title(app.spectrum_simulation, 'Spectrum Fitting')
            app.spectrum_fitting = uiaxes(app.UIFigure);
            title(app.spectrum_fitting, 'Spectrum Fitting Residual')
            app.SpectralFittingforMonophosphonatedTritylRadicalsLabel = uilabel(app.UIFigure);
            app.SpectralFittingforMonophosphonatedTritylRadicalsLabel.Text = 'Spectral Fitting for Monophosphonated Trityl Radicals';
            app.SpectrumDataPanel = uipanel(app.UIFigure);
            app.SpectrumDataPanel.Title = 'Spectrum Data';
            app.SpectrumFilePath = uieditfield(app.SpectrumDataPanel, 'text');
            app.SpectrumFilePath.Editable = 'off';
            app.DataFileLabel = uilabel(app.SpectrumDataPanel);
            app.DataFileLabel.Text = 'Data File:';
            app.SpectrumFileName = uieditfield(app.SpectrumDataPanel, 'text');
            app.SelectfileButton = uibutton(app.SpectrumDataPanel, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.FilePathLabel = uilabel(app.SpectrumDataPanel);
            app.FilePathLabel.Text = 'File Path:';
            app.SelectParametersCalibrationDropDownLabel = uilabel(app.UIFigure);
            app.SelectParametersCalibrationDropDownLabel.Text = 'Select Parameters/Calibration: ';
            app.SelectCalibrationDropDown = uidropdown(app.UIFigure);
            app.SelectCalibrationDropDown.Items = {'0 Custom/Calibrate'};
            app.SelectCalibrationDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectCalibrationDropDownValueChanged, true);
            app.SelectCalibrationDropDown.Value = '0 Custom/Calibrate';
            app.SaveNewCalButton = uibutton(app.UIFigure, 'push');
            app.SaveNewCalButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNewCalButtonPushed, true);
            app.SaveNewCalButton.Text = 'Save New Cal';
            app.EditCalCancelButton = uibutton(app.UIFigure, 'push');
            app.EditCalCancelButton.ButtonPushedFcn = createCallbackFcn(app, @EditCalCancelButtonPushed, true);
            app.EditCalCancelButton.Visible = 'off';
            app.EditCalCancelButton.Text = 'Edit Cal';
            app.SaveChangesButton = uibutton(app.UIFigure, 'push');
            app.SaveChangesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChangesButtonPushed, true);
            app.SaveChangesButton.Visible = 'off';
            app.SaveChangesButton.Text = 'Save Changes';
            app.SaveAsButton = uibutton(app.UIFigure, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Visible = 'off';
            app.SaveAsButton.Text = 'Save As';
            app.DeleteCalButton = uibutton(app.UIFigure, 'push');
            app.DeleteCalButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteCalButtonPushed, true);
            app.DeleteCalButton.Visible = 'off';
            app.DeleteCalButton.Text = 'Delete Cal';
            app.QuickCalButtonsPanel = uipanel(app.UIFigure);
            app.QuickCalButtonsPanel.Title = 'Quick Cal Buttons';
            app.GridLayout3 = uigridlayout(app.QuickCalButtonsPanel);
            app.pH5LWButton = uibutton(app.GridLayout3, 'push');
            app.pH5LWButton.ButtonPushedFcn = createCallbackFcn(app, @pH5LWButtonPushed, true);
            app.pH5LWButton.Text = {'pH 5 LW'; ''};
            app.pH10LWButton = uibutton(app.GridLayout3, 'push');
            app.pH10LWButton.ButtonPushedFcn = createCallbackFcn(app, @pH10LWButtonPushed, true);
            app.pH10LWButton.Text = {'pH 10+ LW'; ''};
            app.pH7LWButton = uibutton(app.GridLayout3, 'push');
            app.pH7LWButton.ButtonPushedFcn = createCallbackFcn(app, @pH7LWButtonPushed, true);
            app.pH7LWButton.Text = 'pH 7 LW';
            app.PiDistButton = uibutton(app.GridLayout3, 'push');
            app.PiDistButton.ButtonPushedFcn = createCallbackFcn(app, @PiDistButtonPushed, true);
            app.PiDistButton.Text = {'0 Pi Dist'; ''};
            app.pH5O2CalButton = uibutton(app.GridLayout3, 'push');
            app.pH5O2CalButton.ButtonPushedFcn = createCallbackFcn(app, @pH5O2CalButtonPushed, true);
            app.pH5O2CalButton.Text = 'pH 5 O2 Cal';
            app.pH10O2CalButton = uibutton(app.GridLayout3, 'push');
            app.pH10O2CalButton.ButtonPushedFcn = createCallbackFcn(app, @pH10O2CalButtonPushed, true);
            app.pH10O2CalButton.Text = 'pH 10+ O2 Cal';
            app.pH7O2CalButton = uibutton(app.GridLayout3, 'push');
            app.pH7O2CalButton.ButtonPushedFcn = createCallbackFcn(app, @pH7O2CalButtonPushed, true);
            app.pH7O2CalButton.Text = 'pH 7 O2 Cal';
            app.PiCalButton = uibutton(app.GridLayout3, 'push');
            app.PiCalButton.ButtonPushedFcn = createCallbackFcn(app, @PiCalButtonPushed, true);
            app.PiCalButton.Text = 'Pi Cal';
            app.pKaCalButton = uibutton(app.GridLayout3, 'push');
            app.pKaCalButton.ButtonPushedFcn = createCallbackFcn(app, @pKaCalButtonPushed, true);
            app.pKaCalButton.Text = 'pKa Cal';
            app.LineFitting = uibutton(app.GridLayout3, 'push');
            app.LineFitting.ButtonPushedFcn = createCallbackFcn(app, @LineFittingButtonPushed, true);
            app.LineFitting.Text = 'Fit Line';
            app.FittingParametersPanel = uipanel(app.UIFigure);
            app.FittingParametersPanel.Title = 'Fitting Parameters';
            app.GridLayout = uigridlayout(app.FittingParametersPanel);
            app.ParameterLabel = uilabel(app.GridLayout);
            app.ParameterLabel.Text = 'Parameter';
            app.StartValueLabel = uilabel(app.GridLayout);
            app.StartValueLabel.Text = 'Start Value';
            app.RangeLabel = uilabel(app.GridLayout);
            app.RangeLabel.Text = 'Range*';
            app.BestFitLabel = uilabel(app.GridLayout);
            app.BestFitLabel.Text = 'Best Fit';
            app.RangeNote_Label = uilabel(app.GridLayout);
            app.RangeNote_Label.Text = {'*Cannot be greater'; 'than Start Value'};
            app.PiTerm_Label = uilabel(app.GridLayout);
            app.PiTerm_Label.Text = 'Pi Term (Hz)';
            app.GaussianLWmGLabel = uilabel(app.GridLayout);
            app.GaussianLWmGLabel.Text = 'Gaussian LW (mG) ';
            app.LWGStart = uieditfield(app.GridLayout, 'numeric');
            app.LWGStart.Limits = [0 Inf];
            app.LWGStart.ValueDisplayFormat = '%11.5g';
            app.LWGStart.ValueChangedFcn = createCallbackFcn(app, @LWGStartValueChanged, true);
            app.LWGStart.Enable = 'off';
            app.LWGStart.Value = 41;
            app.LorentzianALWmGLabel = uilabel(app.GridLayout);
            app.LorentzianALWmGLabel.Text = 'Lorentzian A LW (mG)';
            app.LWLaStart = uieditfield(app.GridLayout, 'numeric');
            app.LWLaStart.Limits = [0 Inf];
            app.LWLaStart.ValueDisplayFormat = '%11.5g';
            app.LWLaStart.ValueChangedFcn = createCallbackFcn(app, @LWLaStartValueChanged, true);
            app.LWLaStart.Enable = 'off';
            app.LWLaStart.Value = 8;
            app.LorentzianBLWmGLabel = uilabel(app.GridLayout);
            app.LorentzianBLWmGLabel.Text = {'Lorentzian B LW (mG)'; ''};
            app.LWLbStart = uieditfield(app.GridLayout, 'numeric');
            app.LWLbStart.Limits = [0 Inf];
            app.LWLbStart.ValueDisplayFormat = '%11.5g';
            app.LWLbStart.ValueChangedFcn = createCallbackFcn(app, @LWLbStartValueChanged, true);
            app.LWLbStart.Enable = 'off';
            app.LWLbStart.Value = 0.02;
            app.ABDistancemGLabel = uilabel(app.GridLayout);
            app.ABDistancemGLabel.Text = 'A/B Distance (mG) ';
            app.ABDistanceStart = uieditfield(app.GridLayout, 'numeric');
            app.ABDistanceStart.ValueDisplayFormat = '%11.5g';
            app.ABDistanceStart.ValueChangedFcn = createCallbackFcn(app, @ABDistanceStartValueChanged, true);
            app.ABDistanceStart.Enable = 'off';
            app.ABDistanceStart.Value = 122;
            app.OxygenLWmGLabel = uilabel(app.GridLayout);
            app.OxygenLWmGLabel.Text = 'Oxygen LW (mG) ';
            app.OxygenLWStart = uieditfield(app.GridLayout, 'numeric');
            app.OxygenLWStart.Limits = [0 Inf];
            app.OxygenLWStart.ValueChangedFcn = createCallbackFcn(app, @OxygenLWStartValueChanged, true);
            app.OxygenLWStart.Value = 50;
            app.AcidicFractionLabel = uilabel(app.GridLayout);
            app.AcidicFractionLabel.Text = 'Acidic Fraction ';
            app.PaStartValue = uieditfield(app.GridLayout, 'numeric');
            app.PaStartValue.Limits = [0 1];
            app.PaStartValue.ValueChangedFcn = createCallbackFcn(app, @PaStartValueValueChanged, true);
            app.PaStartValue.Value = 0.5;
            app.HExchangeHzLabel = uilabel(app.GridLayout);
            app.HExchangeHzLabel.Text = 'H+ Exchange (Hz) ';
            app.kExchangeStart = uieditfield(app.GridLayout, 'numeric');
            app.kExchangeStart.Limits = [1e-05 Inf];
            app.kExchangeStart.ValueChangedFcn = createCallbackFcn(app, @kExchangeStartValueChanged, true);
            app.kExchangeStart.Value = 600000;
            app.CenteroffsetptsLabel = uilabel(app.GridLayout);
            app.CenteroffsetptsLabel.Text = 'Center offset (pts) ';
            app.XpositionStart = uieditfield(app.GridLayout, 'numeric');
            app.PhaseCorrectionLabel = uilabel(app.GridLayout);
            app.PhaseCorrectionLabel.Text = 'Phase Correction (°)';
            app.PhaseCorr = uieditfield(app.GridLayout, 'numeric');
            app.LWGSwitch = uiswitch(app.GridLayout, 'slider');
            app.LWGSwitch.Items = {'Constant', 'Vary'};
            app.LWGSwitch.ValueChangedFcn = createCallbackFcn(app, @LWGSwitchValueChanged, true);
            app.LWGSwitch.Enable = 'off';
            app.LWGSwitch.Value = 'Constant';
            app.LWLaSwitch = uiswitch(app.GridLayout, 'slider');
            app.LWLaSwitch.Items = {'Constant', 'Vary'};
            app.LWLaSwitch.ValueChangedFcn = createCallbackFcn(app, @LWLaSwitchValueChanged, true);
            app.LWLaSwitch.Enable = 'off';
            app.LWLaSwitch.Value = 'Constant';
            app.LWLbSwitch = uiswitch(app.GridLayout, 'slider');
            app.LWLbSwitch.Items = {'Constant', 'Vary'};
            app.LWLbSwitch.ValueChangedFcn = createCallbackFcn(app, @LWLbSwitchValueChanged, true);
            app.LWLbSwitch.Enable = 'off';
            app.LWLbSwitch.Value = 'Constant';
            app.ABDistanceSwitch = uiswitch(app.GridLayout, 'slider');
            app.ABDistanceSwitch.Items = {'Constant', 'Vary'};
            app.ABDistanceSwitch.ValueChangedFcn = createCallbackFcn(app, @ABDistanceSwitchValueChanged, true);
            app.ABDistanceSwitch.Enable = 'off';
            app.ABDistanceSwitch.Value = 'Constant';
            app.OxygenLWSwitch = uiswitch(app.GridLayout, 'slider');
            app.OxygenLWSwitch.Items = {'Constant', 'Vary'};
            app.OxygenLWSwitch.ValueChangedFcn = createCallbackFcn(app, @OxygenLWSwitchValueChanged, true);
            app.OxygenLWSwitch.Value = 'Vary';
            app.PaSwitch = uiswitch(app.GridLayout, 'slider');
            app.PaSwitch.Items = {'Constant', 'Vary'};
            app.PaSwitch.ValueChangedFcn = createCallbackFcn(app, @PaSwitchValueChanged, true);
            app.PaSwitch.Value = 'Vary';
            app.kExchangeSwitch = uiswitch(app.GridLayout, 'slider');
            app.kExchangeSwitch.Items = {'Constant', 'Vary'};
            app.kExchangeSwitch.ValueChangedFcn = createCallbackFcn(app, @kExchangeSwitchValueChanged, true);
            app.kExchangeSwitch.Value = 'Vary';
            app.LWGRange = uieditfield(app.GridLayout, 'numeric');
            app.LWGRange.Limits = [0 Inf];
            app.LWGRange.ValueChangedFcn = createCallbackFcn(app, @LWGRangeValueChanged, true);
            app.LWGRange.Enable = 'off';
            app.LWLaRange = uieditfield(app.GridLayout, 'numeric');
            app.LWLaRange.Limits = [0 Inf];
            app.LWLaRange.ValueChangedFcn = createCallbackFcn(app, @LWLaRangeValueChanged, true);
            app.LWLaRange.Enable = 'off';
            app.LWLbRange = uieditfield(app.GridLayout, 'numeric');
            app.LWLbRange.Limits = [0 Inf];
            app.LWLbRange.ValueChangedFcn = createCallbackFcn(app, @LWLbRangeValueChanged, true);
            app.LWLbRange.Enable = 'off';
            app.ABDistanceRange = uieditfield(app.GridLayout, 'numeric');
            app.ABDistanceRange.Limits = [0 Inf];
            app.ABDistanceRange.ValueChangedFcn = createCallbackFcn(app, @ABDistanceRangeValueChanged, true);
            app.ABDistanceRange.Enable = 'off';
            app.OxygenLWRange = uieditfield(app.GridLayout, 'numeric');
            app.OxygenLWRange.Limits = [0 Inf];
            app.OxygenLWRange.ValueChangedFcn = createCallbackFcn(app, @OxygenLWRangeValueChanged, true);
            app.OxygenLWRange.Value = 50;
            app.PaRange = uieditfield(app.GridLayout, 'numeric');
            app.PaRange.Limits = [0 Inf];
            app.PaRange.ValueChangedFcn = createCallbackFcn(app, @PaRangeValueChanged, true);
            app.PaRange.Value = 0.5;
            app.kExchangeRange = uieditfield(app.GridLayout, 'numeric');
            app.kExchangeRange.Limits = [0 Inf];
            app.kExchangeRange.ValueChangedFcn = createCallbackFcn(app, @kExchangeRangeValueChanged, true);
            app.kExchangeRange.Value = 600000;
            app.LWGResult = uieditfield(app.GridLayout, 'numeric');
            app.LWGResult.ValueDisplayFormat = '%11.5g';
            app.LWGResult.Editable = 'off';
            app.LWLaResult = uieditfield(app.GridLayout, 'numeric');
            app.LWLaResult.ValueDisplayFormat = '%11.5g';
            app.LWLaResult.Editable = 'off';
            app.LWLbResult = uieditfield(app.GridLayout, 'numeric');
            app.LWLbResult.ValueDisplayFormat = '%11.5g';
            app.LWLbResult.Editable = 'off';
            app.ABDistanceResult = uieditfield(app.GridLayout, 'numeric');
            app.ABDistanceResult.ValueDisplayFormat = '%11.5g';
            app.ABDistanceResult.Editable = 'off';
            app.OxygenLWResult = uieditfield(app.GridLayout, 'numeric');
            app.OxygenLWResult.ValueDisplayFormat = '%11.5g';
            app.OxygenLWResult.Editable = 'off';
            app.PaResult = uieditfield(app.GridLayout, 'numeric');
            app.PaResult.ValueDisplayFormat = '%11.5g';
            app.PaResult.Editable = 'off';
            app.kExchangeResult = uieditfield(app.GridLayout, 'numeric');
            app.kExchangeResult.ValueDisplayFormat = '%11.5g';
            app.kExchangeResult.Editable = 'off';
            app.PiTermResult = uieditfield(app.GridLayout, 'numeric');
            app.PiTermResult.ValueDisplayFormat = '%11.5g';
            app.PiTermResult.Editable = 'off';
            app.CenterResult = uieditfield(app.GridLayout, 'numeric');
            app.CenterResult.ValueDisplayFormat = '%11.5g';
            app.CenterResult.Editable = 'off';
            app.PhaseResult = uieditfield(app.GridLayout, 'numeric');
            app.PhaseResult.ValueDisplayFormat = '%11.5g';
            app.PhaseResult.Editable = 'off';
            app.CalibrationsandResultsPanel = uipanel(app.UIFigure);
            app.CalibrationsandResultsPanel.Title = 'Calibrations and Results';
            app.GridLayout2 = uigridlayout(app.CalibrationsandResultsPanel);
            app.OxygenpO2Label = uilabel(app.GridLayout2);
            app.OxygenpO2Label.Text = 'Oxygen (pO2) ';
            app.pHLabel = uilabel(app.GridLayout2);
            app.pHLabel.Text = 'pH ';
            app.PhosphatePiLabel = uilabel(app.GridLayout2);
            app.PhosphatePiLabel.Text = 'Phosphate [Pi] ';
            app.SlopeLabel = uilabel(app.GridLayout2);
            app.SlopeLabel.Text = 'Slope';
            app.ResultLabel = uilabel(app.GridLayout2);
            app.ResultLabel.Text = 'Result';
            app.mmHgLabel = uilabel(app.GridLayout2);
            app.mmHgLabel.Text = 'mmHg';
            app.mMLabel = uilabel(app.GridLayout2);
            app.mMLabel.Text = 'mM';
            app.kfslopeLabel = uilabel(app.GridLayout2);
            app.kfslopeLabel.Text = 'kf (slope)';
            app.pKaLabel = uilabel(app.GridLayout2);
            app.pKaLabel.Text = 'pKa';
            app.CorrelationLabel = uilabel(app.GridLayout2);
            app.CorrelationLabel.Text = 'Correlation';
            app.mGmmHgLabel = uilabel(app.GridLayout2);
            app.mGmmHgLabel.Text = 'mG/mmHg';
            app.s1mMLabel = uilabel(app.GridLayout2);
            app.s1mMLabel.Text = {'s-1 / mM'; ''};
            app.OxygenSlope = uieditfield(app.GridLayout2, 'numeric');
            app.OxygenSlope.ValueDisplayFormat = '%11.3g';
            app.OxygenSlope.Editable = 'off';
            app.OxygenSlope.Value = 0.53127;
            app.pKa = uieditfield(app.GridLayout2, 'numeric');
            app.pKa.ValueDisplayFormat = '%.2f';
            app.pKa.Editable = 'off';
            app.pKa.Value = 6.9;
            app.PhosphateSlope = uieditfield(app.GridLayout2, 'numeric');
            app.PhosphateSlope.ValueDisplayFormat = '%11.3g';
            app.PhosphateSlope.Editable = 'off';
            app.PhosphateSlope.Value = 24365;
            app.OxygenResult = uieditfield(app.GridLayout2, 'numeric');
            app.OxygenResult.ValueDisplayFormat = '%.2f';
            app.pHResult = uieditfield(app.GridLayout2, 'numeric');
            app.pHResult.ValueDisplayFormat = '%.2f';
            app.PhosphateResult = uieditfield(app.GridLayout2, 'numeric');
            app.PhosphateResult.ValueDisplayFormat = '%.2f';
            app.FieldSwitch = uiswitch(app.UIFigure, 'slider');
            app.FieldSwitch.Items = {'G', 'mT'};
            app.FieldSwitch.Value = 'mT';
            app.FitSpectrumButton = uibutton(app.UIFigure, 'push');
            app.FitSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @FitSpectrumButtonPushed, true);
            app.FitSpectrumButton.Text = 'Fit Spectrum';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pTAM_Fitting_App_v2_ClinEPR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end